classdef xd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnviarButton                  matlab.ui.control.Button
        TipodeMovimientoListBox       matlab.ui.control.ListBox
        TipodeMovimientoListBoxLabel  matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        PortMotorDropDown             matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
    end

          methods (Access = private)
            % Detecta los puertos
            function lista = deteccion_de_puertos(app)
                % Obtener una lista de puertos seriales disponibles
                ports = serialportlist;
        
                % Imprimir la lista de puertos seriales disponibles
                lista = {}; % Inicializar lista vacía
                
                for i = 1:numel(ports)
                    lista{end+1} = ports{i}; % Agregar elemento a la lista
                end
                        
                %Concatenación con el menu
                app.PortMotorDropDown.Items = lista;
                % Devolver la lista de puertos seriales
            end
    
        
        function promedio = Data_prom(~, port)
            % Creamos una lista vacía
            lista = [];
            
            % Pedimos al usuario que ingrese num datos y los agregamos a la lista
            for i = 1:10
                while true
                    dato = readline(port);
                    try
                        valor = str2double(dato);
                        lista = [lista, valor];
                        break
                    catch
                        disp("Ingrese un valor numérico");
                    end
                end
            end
            
            % Calculamos el promedio de los valores en la lista
            if numel(lista) > 0
                promedio = mean(lista);
                disp("La lista es:");
                disp(lista);
                disp("El promedio de los valores en la lista es:");
                disp(promedio);
            else
                disp("La lista está vacía");
            end                    
        end
          end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: PortMotorDropDown
        function PortMotorDropDownOpening(app, event)
            % Detecta los puertos
            puertos = deteccion_de_puertos(app);

            if isempty(puertos)
                app.PortMotorDropDown.Enable = 'off';
                app.ApagarButton.Enable = 'off';
                uialert(app.UIFigure, "No se detectaron puertos", "Error")
                return
            end     

            puertos = deteccion_de_puertos(app);
            motor_index = find(strcmp(puertos, app.PortMotorDropDown.Value));

            if isempty(motor_index)
                app.PortMotorDropDown.Enable = 'off';
                app.ConectarButton.Enable = 'off';
                uialert(app.UIFigure, "El puerto seleccionado no está disponible", "Error")
                return
            end

            motor = puertos{motor_index};
            
            % Asignar el puerto serial seleccionado a diferentes funciones
            % Configuración del puerto

            port2 = serialport(motor, 9600, "DataBits", 8, "Parity", "none", "StopBits", 1);
            
            configureTerminator(port2,"CR/LF");

            fopen(port2);
            
            init = strcat('0ho0');
                
            % Codificación a cadena de bytes
            str_encoded = uint8(init);

            % Enviar cadena de bytes
            fwrite(port2,str_encoded );

            fclose(port2);
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            puertos = deteccion_de_puertos(app);
            motor_index = find(strcmp(puertos, app.PortMotorDropDown.Value));

            if isempty(motor_index)
                app.PortMotorDropDown.Enable = 'off';
                app.ConectarButton.Enable = 'off';
                uialert(app.UIFigure, "El puerto seleccionado no está disponible", "Error")
                return
            end

            motor = puertos{motor_index};
            
            % Asignar el puerto serial seleccionado a diferentes funciones
            % Configuración del puerto

            port2 = serialport(motor, 9600, "DataBits", 8, "Parity", "none", "StopBits", 1);
            
            configureTerminator(port2,"CR/LF");

            fopen(port2);
            
            % Conversión a hexadecimal y codificación
            des = app.EditField.Value; % Leer el valor del EditField
            dec_num = (des * pi) / (4.38282 * 10^-5 * 180);
            hexa = dec2hex(int32(dec_num),8);
            
            % Tipo de movimiento
            tipo = app.TipodeMovimientoListBox.Value;
            hexa = strcat('0',tipo, hexa);
                
            % Codificación a cadena de bytes
            str_encoded = uint8(hexa);

            % Enviar cadena de bytes
            fwrite(port2,hexa);

            fclose(port2);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            whos value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.PortMotorDropDown = uidropdown(app.UIFigure);
            app.PortMotorDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortMotorDropDownOpening, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.TipodeMovimientoListBoxLabel = uilabel(app.UIFigure);
            app.TipodeMovimientoListBoxLabel.Text = {'Tipo de'; ' Movimiento'};
            app.TipodeMovimientoListBox = uilistbox(app.UIFigure);
            app.TipodeMovimientoListBox.Items = {'ma', 'mr'};
            app.TipodeMovimientoListBox.Value = 'ma';
            app.EnviarButton = uibutton(app.UIFigure, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end