classdef SPR_ELECTRONIC_PROYECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        EnviarButton                    matlab.ui.control.Button
        TipodeMovimientoListBox         matlab.ui.control.ListBox
        TipodeMovimientoListBoxLabel    matlab.ui.control.Label
        PortArduinoDropDown             matlab.ui.control.DropDown
        PortArduinoDropDownLabel        matlab.ui.control.Label
        PortMotorDropDown               matlab.ui.control.DropDown
        PortMotorDropDownLabel          matlab.ui.control.Label
        nguloaDesaplazarEditField       matlab.ui.control.NumericEditField
        nguloaDesaplazarEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        ApagarButton                    matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

          methods (Access = private)
            % Detecta los puertos
            function lista = deteccion_de_puertos(app)
                % Obtener una lista de puertos seriales disponibles
                ports = serialportlist;
        
                % Imprimir la lista de puertos seriales disponibles
                lista = {}; % Inicializar lista vacía
                
                for i = 1:numel(ports)
                    lista{end+1} = ports{i}; % Agregar elemento a la lista
                end
                        
                %Concatenación con el menu
                app.PortArduinoDropDown.Items = lista;
                app.PortMotorDropDown.Items = lista;
                % Devolver la lista de puertos seriales
            end
    
        
        function promedio = Data_prom(~, port)
            % Creamos una lista vacía
            lista = [];
            
            % Pedimos al usuario que ingrese num datos y los agregamos a la lista
            for i = 1:10
                while true
                    dato = readline(port);
                    try
                        valor = str2double(dato);
                        lista = [lista, valor];
                        break
                    catch
                        disp("Ingrese un valor numérico");
                    end
                end
            end
            
            % Calculamos el promedio de los valores en la lista
            if numel(lista) > 0
                promedio = mean(lista);
                disp("La lista es:");
                disp(lista);
                disp("El promedio de los valores en la lista es:");
                disp(promedio);
            else
                disp("La lista está vacía");
            end                    
        end
          end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: PortArduinoDropDown
        function PortArduinoDropDownOpening(app, event)
            puertos = deteccion_de_puertos(app);
            disp(puertos)
            if isempty(puertos)
                app.PortArduinoDropDown.Enable = 'off';
                app.PortMotorDropDown.Enable = 'off';
                app.ApagarButton.Enable = 'off';
                uialert(app.UIFigure, "No se detectaron puertos", "Error")
                return
            end
            
            % Find the index of the selected port in the list of available ports
            motor_index = find(strcmp(puertos, app.PortMotorDropDown.Value));
            
            % Remove the selected port from the list of available ports
            ardu_index = setdiff(puertos, puertos{motor_index});
            
            % Update the options for the second drop-down
            app.PortMotorDropDown.Items = ardu_index;
            
            % Select the first option in the second drop-down
            app.PortMotorDropDown.Value = ardu_index(1);

            if isempty(ardu_index) || isempty(motor_index)
                app.PortArduinoDropDown.Enable = 'off';
                app.PortMotorDropDown.Enable = 'off';
                app.ConectarButton.Enable = 'off';
                uialert(app.UIFigure, "El puerto seleccionado no está disponible", "Error")
                return
            end

            motor = puertos{motor_index};

            % Asignar el puerto serial seleccionado a diferentes funciones
            % Configuración del puerto
            port2 = serialport(motor, 9600, "DataBits", 8, "Parity", "none", "StopBits", 1);
            
            %Inicializacion
            configureTerminator(port2,"CR/LF");

            fopen(port2);
            
            init = strcat('0ho0');
                
            % Codificación a cadena de bytes
            str_encoded = uint8(init);

            % Enviar cadena de bytes
            fwrite(port2,str_encoded );

            fclose(port2);
        end

        % Value changed function: ApagarButton
        function ApagarButtonValueChanged(app, event)
            value = app.ApagarButton.Value;
            if value % Si el valor es verdadero
                delete(gcf); % Cerrar la figura actual
                delete(app); % Cerrar la aplicación
                % Verificar si los puertos están abiertos
                if strcmp(port1.Status, 'open')
                    % Cerrar el puerto 1
                    fclose(port1);
                end
                if strcmp(port2.Status, 'open')
                    % Cerrar el puerto 2
                    fclose(port2);
                end

            end
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            % Detecta los puertos
            puertos = deteccion_de_puertos(app);
            puertos2 = flip(puertos);
            
            % Asignación de puertos
            ardu_index = strcmp(puertos2, app.PortArduinoDropDown.Value);
            motor_index = strcmp(puertos, app.PortMotorDropDown.Value);
            
            ardu = puertos2{ardu_index};
            motor = puertos{motor_index};
            
            % Asignar el puerto serial seleccionado a diferentes funciones
            % Configuración del puerto

            port2 = serialport(motor, 9600, "DataBits", 8, "Parity", "none", "StopBits", 1);
            
            port1 = serialport(ardu, 9600, "DataBits", 8, "Parity", "none", "StopBits", 1);
            

            %Apertura puerto
            configureTerminator(port2,"CR/LF");

            fopen(port2);

            %configureTerminator(port1,"CR/LF");

            fopen(port1);

            % Llamamos a la función Data_prom y pasamos el objeto serialport como parámetro
            promedio = Data_prom(app, port1);
            % Conversión a hexadecimal y codificación
            des = app.nguloaDesaplazarEditField.Value; % Leer el valor del EditField
            dec_num = (des * pi) / (4.38282 * 10^-5 * 180);
            hexa = dec2hex(int32(dec_num),8);
            
            % Tipo de movimiento
            tipo = app.TipodeMovimientoListBox.Value;
            hexa = strcat('0',tipo, hexa);
                
            % Codificación a cadena de bytes
            str_encoded = uint8(hexa);

            % Enviar cadena de bytes
            fwrite(port2,hexa);            

            % Leer una línea de respuesta
            response = fread(port2, 11, 'uint8');
            disp(response)
            res = response(4:11);
            
            % Convert the uint8 values to a binary string
            binary_str = char(res);
            
            % Concatenate all the binary characters into a single binary string
            hexade = binary_str(:)';

            % Conversión de respuesta a decimal
            res_des = int32(hex2dec(hexade));
            %disp(hex2dec(hexade))
            %disp(res_des)

            % Conversión a grados de la posición del motor
            save_des = (double(res_des)*180/pi)*(4.38282 * 10^-5) ;
            disp(save_des)
            
            % Open the text file for writing
            fileID = fopen('output.txt', 'a');
            
            % Write the variables to the file in the desired format
            fprintf(fileID, '%f %f\n', save_des, promedio);
            
            % Close the text file
            fclose(fileID);

            %       fclose(port1);
            fclose(port2);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            % Open the output.txt file
            fid = fopen('output.txt', 'r');
            if fid == -1
                uialert(app.UIFigure, 'Could not open output.txt', 'Error');
                return
            end
            
            % Read the data from the file
            data = textscan(fid, '%f %f', 'Delimiter', ' ', 'MultipleDelimsAsOne', true);
            x = data{1};
            y = data{2};
            
            % Close the file
            fclose(fid);   
            
            % Plot the data on the UIAxes
            plot(app.UIAxes, x, y);

            %Definición de los límites de los ejes
            xlim(app.UIAxes, [0 90]);
            ylim(app.UIAxes, [0 1]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.nguloaDesaplazarEditFieldLabel = uilabel(app.LeftPanel);
            app.nguloaDesaplazarEditFieldLabel.Text = {'Ángulo a '; 'Desaplazar'};
            app.nguloaDesaplazarEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PortMotorDropDownLabel = uilabel(app.LeftPanel);
            app.PortMotorDropDownLabel.Text = 'Port Motor';
            app.PortMotorDropDown = uidropdown(app.LeftPanel);
            app.PortMotorDropDown.Items = {'Option 1', 'Option 2', 'Option 3'};
            app.PortArduinoDropDownLabel = uilabel(app.LeftPanel);
            app.PortArduinoDropDownLabel.Text = 'Port Arduino';
            app.PortArduinoDropDown = uidropdown(app.LeftPanel);
            app.PortArduinoDropDown.Items = {'Option 2', 'Option 3', 'Option 4'};
            app.PortArduinoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortArduinoDropDownOpening, true);
            app.PortArduinoDropDown.Value = 'Option 2';
            app.TipodeMovimientoListBoxLabel = uilabel(app.LeftPanel);
            app.TipodeMovimientoListBoxLabel.Text = {'Tipo de'; ' Movimiento'};
            app.TipodeMovimientoListBox = uilistbox(app.LeftPanel);
            app.TipodeMovimientoListBox.Items = {'ma', 'mr'};
            app.TipodeMovimientoListBox.Value = 'ma';
            app.EnviarButton = uibutton(app.LeftPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'SPR')
            xlabel(app.UIAxes, 'Angle °')
            ylabel(app.UIAxes, 'Intensity [V]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ApagarButton = uibutton(app.RightPanel, 'state');
            app.ApagarButton.ValueChangedFcn = createCallbackFcn(app, @ApagarButtonValueChanged, true);
            app.ApagarButton.Text = 'Apagar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPR_ELECTRONIC_PROYECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end